classdef prescribe_cmp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        coronalMR               matlab.ui.control.UIAxes
        sagittalMR              matlab.ui.control.UIAxes
        MRImagesLabel           matlab.ui.control.Label
        SimulationLabel         matlab.ui.control.Label
        SimulateButton          matlab.ui.control.Button
        SelectFocalSpotButton   matlab.ui.control.Button
        coronalSim              matlab.ui.control.UIAxes
        sagittalSim             matlab.ui.control.UIAxes
        SelectImagesButton      matlab.ui.control.Button
        coronalSlider           matlab.ui.control.Slider
        coronalLeft             matlab.ui.control.Button
        coronalRight            matlab.ui.control.Button
        sagSlider               matlab.ui.control.Slider
        sagLeft                 matlab.ui.control.Button
        sagRight                matlab.ui.control.Button
        SelectTxLocationButton  matlab.ui.control.Button
        lowWindowUp             matlab.ui.control.Button
        lowWindowDown           matlab.ui.control.Button
        WindowLabel             matlab.ui.control.Label
        highWindowUp            matlab.ui.control.Button
        highWindowDown          matlab.ui.control.Button
        defaultWindow           matlab.ui.control.Button
        SaveNameEditFieldLabel  matlab.ui.control.Label
        SaveNameEditField       matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        % Displays the coronal and sagittal images
        % @INPUTS
        %    app: pointer to parent GUI
        %    coronalImgVolume: 3D MR Data
        %    corWindow: window in which to display data
        %    corIdx: current coronal image index
        %    sagIdx: current sagittal image index
        %    xAx: limits of image in x (P/A) dimension
        %    yAx: limits of image in y (R/L) dimension
        % @Global INPUTS - These are set by the simulation so that they are only used if a simulation has been performed
        %    zAx: limits of image in z (S/I) dimension
        %    simulationCorIdx: Index of coronal images for which a simulation was performed
        
        function displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx)
            global p zAx simulationCorIdx;
            
            curCorImage = squeeze(coronalImgVolume(:,:,corIdx));
            curSagImage = squeeze(coronalImgVolume(:,sagIdx,:));
            
            curCorImage(:,sagIdx) = 10*max(curCorImage(:));
            curSagImage(:,corIdx) = 10*max(curSagImage(:));
            
            imshow(curCorImage,corWindow,'Parent',app.coronalMR,'InitialMagnification','fit');
            imshow(curSagImage,corWindow,'Parent',app.sagittalMR,'xData',xAx,'yData',yAx);
            
            if ~isnan(p)
                tmp = squeeze(abs(p(:,sagIdx,:)));
                simImg = zeros(size(curSagImage));
                simImg(:,simulationCorIdx) = tmp;
                mrImg = squeeze(coronalImgVolume(:,sagIdx,:));
                overlayImages(mrImg,simImg,app.sagittalSim,zAx,yAx,0.5*max(abs(p(:))),corWindow)
                
                if ismember(corIdx,simulationCorIdx)
                    simImg = squeeze(abs(p(:,:,simulationCorIdx == corIdx)));
                    
                    mrImg = squeeze(coronalImgVolume(:,:,corIdx));
                    overlayImages(mrImg,simImg,app.coronalSim,zAx,yAx,0.5*max(abs(p(:))),corWindow)
                else
                    cla(app.coronalSim);
                end
            else
                cla(app.coronalSim);
                cla(app.sagittalSim);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Currently defining the x axis as the slice dimension (from front to back of head), 
            % the z axis as pointing from top of head to bottom of head (matching the z-axis for 
            % the trnasducer), and the y-axis as pointing from one side of the head to the other.
            
            global coronalImgVolume sagDim xAx yAx zAx focus corWindow p;
            
            % p is the simulated pressure. Set to nan to show that pressure hasn't been simulated yet
            p = nan;
            
            addpath('gui_lib');
            
            % Load a default image path
            imgPath = 'C:\Users\Taylor\Documents\Data\MR\Macaque\18623_08252016\004\';
            
            [coronalImgVolume,header] = loadDicomDir(imgPath,app.UIFigure);
            
            % Assuming this volume has coronal slices
            zDim = 3;
            sagDim = 2;
            
            % Axes
            yAx = [0,header{1}.PixelSpacing(1)*size(coronalImgVolume,2)];
            zAx = [0,header{1}.PixelSpacing(2)*size(coronalImgVolume,1)];
            xAx = [0,header{1}.SpacingBetweenSlices*size(coronalImgVolume,zDim)];
            
            focus = [sum(yAx)/2, sum(zAx)/2, sum(xAx)/2];
            
            % Display Images
            curCorImage = squeeze(coronalImgVolume(:,:,ceil(size(coronalImgVolume,zDim)/2)));
            corWindow = [min(curCorImage(:)),max(curCorImage(:))];
            
            displayMrImages(app,coronalImgVolume,corWindow,ceil(size(coronalImgVolume,zDim)/2),ceil(size(coronalImgVolume,2)/2),xAx,yAx);
            
            % Set up proper limits on the sliders
            set(app.coronalSlider,'Limits',[1,size(coronalImgVolume,zDim)]);
            set(app.coronalSlider,'Value',ceil(size(coronalImgVolume,zDim)/2));
            
            set(app.sagSlider,'Limits',[1,size(coronalImgVolume,sagDim)]);
            set(app.sagSlider,'Value',ceil(size(coronalImgVolume,sagDim)/2));
        end

        % Button pushed function: SelectImagesButton
        function SelectImagesButtonPushed(app, event)
            % Allow the user to select a different set of coronal images
            global coronalImgVolume sagDim xAx yAx zAx p;
            
            % p is the simulated pressure. Loading a new data set invalidates it
            p = nan;
            
            imgPath = uigetdir();
            if ~imgPath % User cancelled
                return
            end
            addpath('gui_lib');
            % Currently defining the x axis as the slice dimension (from front to back of head), 
            % the z axis as pointing from top of head to bottom of head (matching the z-axis for 
            % the trnasducer), and the y-axis as pointing from one side of the head to the other.                        
            [coronalImgVolume,header] = loadDicomDir(imgPath);
            
            % Assuming this volume has coronal slices
            zDim = 3;
            sagDim = 2;
            
            % Axes
            yAx = [0,header{1}.PixelSpacing(1)*size(coronalImgVolume,1)];
            zAx = [0,header{1}.PixelSpacing(2)*size(coronalImgVolume,2)];
            xAx = [0,header{1}.SpacingBetweenSlices*size(coronalImgVolume,zDim)];
            
            % Display Images
            curCorImage = squeeze(coronalImgVolume(:,:,ceil(size(coronalImgVolume,zDim)/2)));
            corWindow = [min(curCorImage(:)),max(curCorImage(:))];
            
            displayMrImages(app,coronalImgVolume,corWindow,ceil(size(coronalImgVolume,zDim)/2),ceil(size(coronalImgVolume,2)/2),xAx,yAx);
            
            % Set up proper limits on the sliders
            set(app.coronalSlider,'Limits',[1,size(coronalImgVolume,zDim)]);
            set(app.coronalSlider,'Value',ceil(size(coronalImgVolume,zDim)/2));
            
            set(app.sagSlider,'Limits',[1,size(coronalImgVolume,sagDim)]);
            set(app.sagSlider,'Value',ceil(size(coronalImgVolume,sagDim)/2));
            
        end

        % Value changed function: coronalSlider
        function coronalSliderValueChanged(app, event)
            global coronalImgVolume corWindow xAx yAx;
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            set(app.coronalSlider,'Value',round(corIdx));
            
            displayMrImages(app,coronalImgVolume,corWindow,round(corIdx),sagIdx,xAx,yAx);
        end

        % Button pushed function: coronalLeft
        function coronalLeftButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx;
            
            curSliderLoc = get(app.coronalSlider,'Value');
            sagIdx = get(app.sagSlider,'Value');
            if curSliderLoc == 1
                return
            else 
                corIdx = curSliderLoc-1;
                set(app.coronalSlider,'Value',corIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Button pushed function: coronalRight
        function coronalRightButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx;
            
            curSliderLoc = get(app.coronalSlider,'Value');
            sagIdx = get(app.sagSlider,'Value');
            if curSliderLoc == size(coronalImgVolume,3)
                return
            else 
                corIdx = curSliderLoc+1;
                set(app.coronalSlider,'Value',corIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Value changed function: sagSlider
        function sagSliderValueChanged(app, event)
            global coronalImgVolume xAx yAx corWindow;
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            
            set(app.sagSlider,'Value',round(sagIdx));
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,round(sagIdx),xAx,yAx);
        end

        % Button pushed function: sagLeft
        function sagLeftButtonPushed(app, event)
            global coronalImgVolume xAx yAx corWindow;
            
            curSliderLoc = get(app.sagSlider,'Value');
            corIdx = get(app.coronalSlider,'Value');
            if curSliderLoc == 1
                return
            else 
                sagIdx = curSliderLoc-1;
                set(app.sagSlider,'Value',sagIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Button pushed function: sagRight
        function sagRightButtonPushed(app, event)
            global coronalImgVolume sagDim xAx yAx corWindow;
            
            curSliderLoc = get(app.sagSlider,'Value');
            corIdx = get(app.coronalSlider,'Value');
            if curSliderLoc == size(coronalImgVolume,sagDim)
                return
            else 
                sagIdx = curSliderLoc+1;
                set(app.sagSlider,'Value',sagIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Button pushed function: SelectTxLocationButton
        function SelectTxLocationButtonPushed(app, event)
            global coronalImgVolume xAx yAx zAx centerX centerY centerZ xTx yTx zTx corWindow;
            volIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            h = figure;
            imshow(coronalImgVolume(:,:,volIdx),corWindow,'xData',yAx,'yData',zAx);
            
            [centerY,centerZ] = ginput(1);
            centerX = volIdx;
            
            close(h);
            
            x = linspace(xAx(1),xAx(2),size(coronalImgVolume,3));
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2));
            z = linspace(zAx(1),zAx(2),size(coronalImgVolume,1));
            
            % Because of the way that xAx and yAx are defined in this code I switched the order of x and y
            [~,yTx,xTx,zTx] = transducerGeometry(0);
            
            windowSize = 3; % How many voxels to brighten for each element location
            
            xIdx = zeros(size(xTx));
            yIdx = zeros(size(yTx));
            zIdx = zeros(size(zTx));
            for ii = 1:length(xTx)
                [~,xIdx(ii)] = min(abs((xTx(ii)+x(centerX))-x));
                [~,yIdx(ii)] = min(abs((yTx(ii)+centerY)-y));
                [~,zIdx(ii)] = min(abs((zTx(ii)+centerZ)-z));
                
                zAll = (zIdx(ii)-windowSize):(zIdx(ii)+windowSize);
                zAll(zAll < 1) = 1;
                zAll(zAll > size(coronalImgVolume,1)) = size(coronalImgVolume,1);
                
                xAll = (xIdx(ii)-windowSize):(xIdx(ii)+windowSize);
                xAll(xAll < 1) = 1;
                xAll(xAll > size(coronalImgVolume,3)) = size(coronalImgVolume,3);
                
                yAll = (yIdx(ii)-windowSize):(yIdx(ii)+windowSize);
                yAll(yAll < 1) = 1;
                yAll(yAll > size(coronalImgVolume,2)) = size(coronalImgVolume,2);
                
                coronalImgVolume(zAll,yAll,xAll) = max(coronalImgVolume(:));
            end
            displayMrImages(app,coronalImgVolume,corWindow,volIdx,sagIdx,xAx,yAx);

            xTx = xTx+x(centerX);
            yTx = yTx+centerY;
            zTx = zTx+centerZ;
            
        end

        % Button pushed function: SelectFocalSpotButton
        function SelectFocalSpotButtonPushed(app, event)
            global coronalImgVolume xAx yAx zAx focus corWindow;
            volIdx = app.coronalSlider.Value;
            h = figure;
            imshow(coronalImgVolume(:,:,volIdx),corWindow,'xData',yAx,'yData',zAx);
            
            [focalY,focalZ] = ginput(1);
            focalX = volIdx;
            x = linspace(xAx(1),xAx(2),size(coronalImgVolume,3));
            
            focus = [x(round(focalX)), focalY, focalZ];
            close(h);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            global coronalImgVolume xAx yAx zAx focus xTx yTx zTx corWindow p simulationCorIdx;
            
            if strcmp(app.SaveNameEditField.Value,'MAT File')
                msgbox('You must supply a file name to save the results!')
                return
            elseif exist(app.SaveNameEditField.Value,'file') || exist([app.SaveNameEditField.Value,'.mat'],'file')
                overwrite = questdlg([app.SaveNameEditField.Value, ' already exists. Overwrite?'],'Overwrite?','Yes','No','No');
                if strcmp(overwrite, 'No')
                    msgbox('Please supply a different file name')
                    return
                end
            end
            
            frequency = 650e3;
            
            volIdx = app.coronalSlider.Value;
            
            elements.x = xTx*1e-3;
            elements.y = yTx*1e-3;
            elements.z = zTx*1e-3;
            
            elements = steerArray(elements,focus*1e-3,frequency);
            elements.p = ones(size(xTx));
            
            % Simulate Coronal Plane
            x = linspace(xAx(1),xAx(2),size(coronalImgVolume,3))*1e-3;
            simulationCorIdx = find(x>min(xTx*1e-3)&x<max(xTx*1e-3));
            xGrid = x(simulationCorIdx);
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2))*1e-3;
            z = linspace(zAx(1),zAx(2),size(coronalImgVolume,1))*1e-3;
            
            [Y,Z,X] = meshgrid(y,z,xGrid);
            grid.X = X;
            grid.Y = Y;
            grid.Z = Z;
            
            p = simulateArray(elements,frequency,grid,app.UIFigure);
            
            [~,focalSagIdx] = min(abs(y-focus(2)*1e-3));
            set(app.sagSlider,'Value',focalSagIdx);
            
            displayMrImages(app,coronalImgVolume,corWindow,volIdx,focalSagIdx,xAx,yAx);
            
            save(app.SaveNameEditField.Value,...
                'coronalImgVolume','corWindow','volIdx','focalSagIdx','xAx','yAx','p','simulationCorIdx','xAx','yAx','zAx','focus','xTx','yTx','zTx');

        end

        % Button pushed function: highWindowUp
        function highWindowUpButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            corWindow(2) = corWindow(2)/25+corWindow(2);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: highWindowDown
        function highWindowDownButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            corWindow(2) = -corWindow(2)/25+corWindow(2);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: lowWindowUp
        function lowWindowUpButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            corWindow(1) = corWindow(1)/25+corWindow(1);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: lowWindowDown
        function lowWindowDownButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            corWindow(1) = -corWindow(1)/25+corWindow(1);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: defaultWindow
        function defaultWindowButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            
            curCorImage = squeeze(coronalImgVolume(:,:,corIdx));
            corWindow = [min(curCorImage(:)),max(curCorImage(:))];
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Value changed function: SaveNameEditField
        function SaveNameEditFieldValueChanged(app, event)
            global saveFileName;
            saveFileName = app.SaveNameEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.coronalMR = uiaxes(app.UIFigure);
            title(app.coronalMR, 'Coronal')
            app.coronalMR.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.coronalMR.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.sagittalMR = uiaxes(app.UIFigure);
            title(app.sagittalMR, 'Sagittal')
            app.MRImagesLabel = uilabel(app.UIFigure);
            app.MRImagesLabel.Text = 'MR Images';
            app.SimulationLabel = uilabel(app.UIFigure);
            app.SimulationLabel.Text = 'Simulation';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SelectFocalSpotButton = uibutton(app.UIFigure, 'push');
            app.SelectFocalSpotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFocalSpotButtonPushed, true);
            app.SelectFocalSpotButton.Text = 'Select Focal Spot';
            app.coronalSim = uiaxes(app.UIFigure);
            title(app.coronalSim, 'Coronal')
            xlabel(app.coronalSim, 'mm')
            ylabel(app.coronalSim, 'mm')
            app.sagittalSim = uiaxes(app.UIFigure);
            title(app.sagittalSim, 'Sagittal')
            xlabel(app.sagittalSim, 'mm')
            ylabel(app.sagittalSim, 'mm')
            app.SelectImagesButton = uibutton(app.UIFigure, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesButtonPushed, true);
            app.SelectImagesButton.Text = 'Select Images';
            app.coronalSlider = uislider(app.UIFigure);
            app.coronalSlider.MajorTicks = [];
            app.coronalSlider.ValueChangedFcn = createCallbackFcn(app, @coronalSliderValueChanged, true);
            app.coronalSlider.MinorTicks = [];
            app.coronalLeft = uibutton(app.UIFigure, 'push');
            app.coronalLeft.ButtonPushedFcn = createCallbackFcn(app, @coronalLeftButtonPushed, true);
            app.coronalLeft.Text = '<';
            app.coronalRight = uibutton(app.UIFigure, 'push');
            app.coronalRight.ButtonPushedFcn = createCallbackFcn(app, @coronalRightButtonPushed, true);
            app.coronalRight.Text = '>';
            app.sagSlider = uislider(app.UIFigure);
            app.sagSlider.MajorTicks = [];
            app.sagSlider.ValueChangedFcn = createCallbackFcn(app, @sagSliderValueChanged, true);
            app.sagSlider.MinorTicks = [];
            app.sagLeft = uibutton(app.UIFigure, 'push');
            app.sagLeft.ButtonPushedFcn = createCallbackFcn(app, @sagLeftButtonPushed, true);
            app.sagLeft.Text = '<';
            app.sagRight = uibutton(app.UIFigure, 'push');
            app.sagRight.ButtonPushedFcn = createCallbackFcn(app, @sagRightButtonPushed, true);
            app.sagRight.Text = '>';
            app.SelectTxLocationButton = uibutton(app.UIFigure, 'push');
            app.SelectTxLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTxLocationButtonPushed, true);
            app.SelectTxLocationButton.Text = 'Select Tx Location';
            app.lowWindowUp = uibutton(app.UIFigure, 'push');
            app.lowWindowUp.ButtonPushedFcn = createCallbackFcn(app, @lowWindowUpButtonPushed, true);
            app.lowWindowUp.Text = '^';
            app.lowWindowDown = uibutton(app.UIFigure, 'push');
            app.lowWindowDown.ButtonPushedFcn = createCallbackFcn(app, @lowWindowDownButtonPushed, true);
            app.lowWindowDown.Text = 'v';
            app.WindowLabel = uilabel(app.UIFigure);
            app.WindowLabel.Text = 'Window';
            app.highWindowUp = uibutton(app.UIFigure, 'push');
            app.highWindowUp.ButtonPushedFcn = createCallbackFcn(app, @highWindowUpButtonPushed, true);
            app.highWindowUp.Text = '^';
            app.highWindowDown = uibutton(app.UIFigure, 'push');
            app.highWindowDown.ButtonPushedFcn = createCallbackFcn(app, @highWindowDownButtonPushed, true);
            app.highWindowDown.Text = 'v';
            app.defaultWindow = uibutton(app.UIFigure, 'push');
            app.defaultWindow.ButtonPushedFcn = createCallbackFcn(app, @defaultWindowButtonPushed, true);
            app.defaultWindow.Text = 'Default';
            app.SaveNameEditFieldLabel = uilabel(app.UIFigure);
            app.SaveNameEditFieldLabel.Text = 'Save Name';
            app.SaveNameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveNameEditField.ValueChangedFcn = createCallbackFcn(app, @SaveNameEditFieldValueChanged, true);
            app.SaveNameEditField.Value = 'MAT File';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = prescribe_cmp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end